classdef ESDII_LAB3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        RightImageDropDown         matlab.ui.control.DropDown
        RightImageDropDownLabel    matlab.ui.control.Label
        LeftImageDropDown          matlab.ui.control.DropDown
        LeftImageDropDownLabel     matlab.ui.control.Label
        SetButton_2                matlab.ui.control.Button
        SetButton                  matlab.ui.control.Button
        PixelSizemmSpinner         matlab.ui.control.Spinner
        PixelSizemmSpinnerLabel    matlab.ui.control.Label
        FocalLengthmmSpinner       matlab.ui.control.Spinner
        FocalLengthmmSpinnerLabel  matlab.ui.control.Label
        BaseLinemmSpinner          matlab.ui.control.Spinner
        BaseLinemmSpinnerLabel     matlab.ui.control.Label
        CenterPanel                matlab.ui.container.Panel
        ZmmTextArea                matlab.ui.control.TextArea
        ZmmTextAreaLabel           matlab.ui.control.Label
        YmmTextArea                matlab.ui.control.TextArea
        YmmTextAreaLabel           matlab.ui.control.Label
        XmmTextArea                matlab.ui.control.TextArea
        XmmTextAreaLabel           matlab.ui.control.Label
        RightPanel                 matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    properties (Access = private)
        baseline = 60;
        focalLength = 6;
        pixelSize = 0.006;
        rightCamera = [0,0];
        leftCamera = [0,0];
        cframe = [376,240];
    end
    
    methods (Access = private)
   
        function [Z,X,Y] = depth(~,xLeft,yLeft,xRight,~,cx, b,f,ps)
            d = (abs((xLeft-cx)-(xRight-cx))*ps);  % disparity [mm]
            Z = (b * f)./d;                        % depth [mm]
            X = (Z .* (xLeft-cx)*ps)/f;
            Y = (Z .* (yLeft-cx)*ps)/f;
        end

        function centers(app)
            switch (app.LeftImageDropDown.Value)
                case 'left5'
                    LeftC = imread('left5.jpg');
                case 'left6'
                    LeftC = imread('left6.jpg');
                case 'left7'
                    LeftC = imread('left7.jpg');
                case 'left8'
                    LeftC = imread('left8.jpg');
                case 'left9'
                    LeftC = imread('left9.jpg');
            end

            switch (app.RightImageDropDown.Value)
                case 'right5'
                    RightC = imread('right5.jpg');
                case 'right6'
                    RightC = imread('right6.jpg');
                case 'right7'
                    RightC = imread('right7.jpg');
                case 'right8'
                    RightC = imread('right8.jpg');
                case 'right9'
                    RightC = imread('right9.jpg');
            end
            LeftG = rgb2gray(LeftC);
            LeftB = edge(LeftG,"sobel",0.05,"both","nothinning");
            subplot(1,2,2);
            imshow(LeftB);
            [app.leftCamera,r] = imfindcircles(LeftB,[20,100]);
            viscircles(app.leftCamera, r,'EdgeColor','b');
            
            RightG = rgb2gray(RightC);
            RightB = edge(RightG ,"sobel",0.05,"both","nothinning");
            subplot(1,2,1);
            imshow(RightB);
            [app.rightCamera,r] = imfindcircles(RightB,[20,100]);
            viscircles(app.rightCamera, r,'EdgeColor','b');
        end 

        function calc(app)
            [Z,X,Y] = depth(app,app.rightCamera(1),app.rightCamera(2), ...
                                app.leftCamera(1),app.leftCamera(2), ...
                                app.cframe(1),app.baseline, ...
                                app.focalLength, app.pixelSize);
            Z_str = sprintf('%1.2f',Z);
            X_str = sprintf('%1.2f',X);
            Y_str = sprintf('%1.2f',Y);
            app.ZmmTextArea.Value = {Z_str};
            app.XmmTextArea.Value = {X_str};
            app.YmmTextArea.Value = {Y_str};
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            app.baseline = app.BaseLinemmSpinner.Value;
            app.focalLength = app.FocalLengthmmSpinner.Value;
            app.pixelSize = app.PixelSizemmSpinner.Value;
            centers(app);
            calc(app);
        end

        % Callback function: CenterPanel, SetButton_2
        function SetButton_2Pushed(app, event)
            centers(app);
            calc(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {528, 528, 528};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {528, 528};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {240, '1x', 356};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.BaseLinemmSpinnerLabel = uilabel(app.LeftPanel);
            app.BaseLinemmSpinnerLabel.Text = 'BaseLine (mm)';
            app.BaseLinemmSpinner = uispinner(app.LeftPanel);
            app.BaseLinemmSpinner.Step = 0.5;
            app.BaseLinemmSpinner.Limits = [0.5 100];
            app.BaseLinemmSpinner.Value = 60;
            app.FocalLengthmmSpinnerLabel = uilabel(app.LeftPanel);
            app.FocalLengthmmSpinnerLabel.Text = 'Focal Length (mm)';
            app.FocalLengthmmSpinner = uispinner(app.LeftPanel);
            app.FocalLengthmmSpinner.Step = 0.5;
            app.FocalLengthmmSpinner.Limits = [0.5 10];
            app.FocalLengthmmSpinner.Value = 6;
            app.PixelSizemmSpinnerLabel = uilabel(app.LeftPanel);
            app.PixelSizemmSpinnerLabel.Text = 'Pixel Size (mm)';
            app.PixelSizemmSpinner = uispinner(app.LeftPanel);
            app.PixelSizemmSpinner.Step = 0.0005;
            app.PixelSizemmSpinner.Limits = [0.0005 0.01];
            app.PixelSizemmSpinner.Value = 0.006;
            app.SetButton = uibutton(app.LeftPanel, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set  ';
            app.SetButton_2 = uibutton(app.LeftPanel, 'push');
            app.SetButton_2.ButtonPushedFcn = createCallbackFcn(app, @SetButton_2Pushed, true);
            app.SetButton_2.Text = 'Set';
            app.LeftImageDropDownLabel = uilabel(app.LeftPanel);
            app.LeftImageDropDownLabel.Text = 'Left Image';
            app.LeftImageDropDown = uidropdown(app.LeftPanel);
            app.LeftImageDropDown.Items = {'left5', 'left6', 'left7', 'left8', 'left9'};
            app.LeftImageDropDown.Value = 'left5';
            app.RightImageDropDownLabel = uilabel(app.LeftPanel);
            app.RightImageDropDownLabel.Text = 'Right Image';
            app.RightImageDropDown = uidropdown(app.LeftPanel);
            app.RightImageDropDown.Items = {'right5', 'right6', 'right7', 'right8', 'right9'};
            app.RightImageDropDown.Value = 'right5';
            app.CenterPanel = uipanel(app.GridLayout);
            app.CenterPanel.ButtonDownFcn = createCallbackFcn(app, @SetButton_2Pushed, true);
            app.XmmTextAreaLabel = uilabel(app.CenterPanel);
            app.XmmTextAreaLabel.Text = 'X(mm) ';
            app.XmmTextArea = uitextarea(app.CenterPanel);
            app.XmmTextArea.Editable = 'off';
            app.XmmTextArea.Placeholder = '-';
            app.YmmTextAreaLabel = uilabel(app.CenterPanel);
            app.YmmTextAreaLabel.Text = 'Y(mm) ';
            app.YmmTextArea = uitextarea(app.CenterPanel);
            app.YmmTextArea.Editable = 'off';
            app.YmmTextArea.Placeholder = '-';
            app.ZmmTextAreaLabel = uilabel(app.CenterPanel);
            app.ZmmTextAreaLabel.Text = 'Z(mm) ';
            app.ZmmTextArea = uitextarea(app.CenterPanel);
            app.ZmmTextArea.Editable = 'off';
            app.ZmmTextArea.Placeholder = '-';
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ESDII_LAB3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end