classdef CPET563_lab4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        RunButton             matlab.ui.control.Button
        RightPanel            matlab.ui.container.Panel
        RightYEditField       matlab.ui.control.EditField
        RightYEditFieldLabel  matlab.ui.control.Label
        RightXEditField       matlab.ui.control.EditField
        RightXEditFieldLabel  matlab.ui.control.Label
        LeftYEditField        matlab.ui.control.EditField
        LeftYEditFieldLabel   matlab.ui.control.Label
        ZEditField            matlab.ui.control.EditField
        ZEditFieldLabel       matlab.ui.control.Label
        LeftXEditField        matlab.ui.control.EditField
        LeftXEditFieldLabel   matlab.ui.control.Label
        DropDown              matlab.ui.control.DropDown
        DropDownLabel         matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        server_ip   = '127.0.0.1';     %IP address of the Unity Server
        server_port = 55001;           %Server Port of the Unity Sever
        pose = [-.5,0,0,0,0,0];
        rc = zeros(20,2);
        lc = zeros(20,2);
        mc = zeros(20,3);
    end
    
    methods (Access = private)
        
        function moveCamera(app,client)
           move = -2.4:0.1:app.pose(1);
           for i = 1:length(move)
               % x,y,z,yaw[z],pitch[y],roll[x]
               subplot(1,2,2)
               app.pose(1) = move(i);
               app.pose(2) = 0.03;
               unityImage_right = unityLink(client,app.pose);
               unityImage_right_g = rgb2gray(unityImage_right);
               unityImage_right_s = edge(unityImage_right_g ,"sobel",0.01,"both","nothinning");
               imshow(unityImage_right_s);
               [app.rc(i,:),~] = imfindcircles(unityImage_right_s ,[10,140]);
               subplot(1,2,1)
               app.pose(2) = -.03;
               unityImage_left = unityLink(client,app.pose);
               unityImage_left_g = rgb2gray(unityImage_left);
               unityImage_left_s  = edge(unityImage_left_g ,"sobel",0.01,"both","nothinning");
               imshow(unityImage_left_s);
               [app.lc(i,:), ~] = imfindcircles(unityImage_left_s,[10,140]);
           end
        end
        
        function M = depth(app,cx,b,f,ps)
            d = (abs((app.lc(:,1)-cx(1))-(app.rc(:,1)-cx(1)))*ps);  % disparity [mm]
            Z = (b * f)./d;                                   % depth [mm]
            X = (Z .* (app.lc(:,1)-cx(1))*ps)/f;
            Y = (Z .* (app.lc(:,1)-cx(2))*ps)/f;
            M = [X,Y,Z];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
           client = tcpclient(app.server_ip,app.server_port);
           %fprintf(1,"Connected to server\n");
           moveCamera(app,client);
           app.mc = depth(app,[376,240],60,6,.020);
           delete(client);
           %fprintf(1,"Disconnected from server\n");
        end

        % Callback function: DropDown, DropDown, DropDown
        function DroppedDownCallBack(app, event)
            count = 1:20;
            map = containers.Map(app.DropDown.Items, count);
            app.LeftYEditField.Value  = string(app.lc(map(app.DropDown.Value),2));
            app.LeftXEditField.Value  = string(app.lc(map(app.DropDown.Value),1));
            app.RightYEditField.Value = string(app.rc(map(app.DropDown.Value),2));
            app.RightXEditField.Value = string(app.rc(map(app.DropDown.Value),1));
            app.ZEditField.Value      = string(app.mc(map(app.DropDown.Value),3));
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {165, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.RightPanel = uipanel(app.GridLayout);
            app.DropDownLabel = uilabel(app.RightPanel);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.RightPanel);
            app.DropDown.Items = {'Frame 1', 'Frame 2', 'Frame 3', 'Frame 4', 'Frame 5', 'Frame 6', 'Frame 7', 'Frame 8', 'Frame 9', 'Frame 10', 'Frame 11', 'Frame 12', 'Frame 13', 'Frame 14', 'Frame 15', 'Frame 16', 'Frame 17', 'Frame 18', 'Frame 19', 'Frame 20'};
            app.DropDown.DropDownOpeningFcn = createCallbackFcn(app, @DroppedDownCallBack, true);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DroppedDownCallBack, true);
            app.DropDown.ClickedFcn = createCallbackFcn(app, @DroppedDownCallBack, true);
            app.DropDown.Value = 'Frame 1';
            app.LeftXEditFieldLabel = uilabel(app.RightPanel);
            app.LeftXEditFieldLabel.Text = {'Left X'; ''};
            app.LeftXEditField = uieditfield(app.RightPanel, 'text');
            app.LeftXEditField.Placeholder = '-';
            app.ZEditFieldLabel = uilabel(app.RightPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.RightPanel, 'text');
            app.ZEditField.Value = '-';
            app.LeftYEditFieldLabel = uilabel(app.RightPanel);
            app.LeftYEditFieldLabel.Text = 'Left Y';
            app.LeftYEditField = uieditfield(app.RightPanel, 'text');
            app.LeftYEditField.Value = '-';
            app.RightXEditFieldLabel = uilabel(app.RightPanel);
            app.RightXEditFieldLabel.Text = 'Right X';
            app.RightXEditField = uieditfield(app.RightPanel, 'text');
            app.RightXEditField.Placeholder = '-';
            app.RightYEditFieldLabel = uilabel(app.RightPanel);
            app.RightYEditFieldLabel.Text = 'Right  Y';
            app.RightYEditField = uieditfield(app.RightPanel, 'text');
            app.RightYEditField.Value = '-';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CPET563_lab4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end